classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Panel4                  matlab.ui.container.Panel
        Button5_13              matlab.ui.control.Button
        Button5_12              matlab.ui.control.Button
        Button5_11              matlab.ui.control.Button
        Button5_10              matlab.ui.control.Button
        Button5_9               matlab.ui.control.Button
        Button_17               matlab.ui.control.Button
        Button_13               matlab.ui.control.Button
        Button_12               matlab.ui.control.Button
        Button_11               matlab.ui.control.Button
        Button_16               matlab.ui.control.Button
        Button_15               matlab.ui.control.Button
        Button_14               matlab.ui.control.Button
        Panel5                  matlab.ui.container.Panel
        CheckBox4               matlab.ui.control.CheckBox
        CheckBox3               matlab.ui.control.CheckBox
        CheckBox2               matlab.ui.control.CheckBox
        CheckBox                matlab.ui.control.CheckBox
        Button4                 matlab.ui.control.Button
        DropDown3               matlab.ui.control.DropDown
        DropDown3Label          matlab.ui.control.Label
        UIAxes2                 matlab.ui.control.UIAxes
        Panel3                  matlab.ui.container.Panel
        Slider3_2               matlab.ui.control.Slider
        Slider3_2Label          matlab.ui.control.Label
        Slider3                 matlab.ui.control.Slider
        Slider3Label            matlab.ui.control.Label
        Button3_2               matlab.ui.control.Button
        DropDown2               matlab.ui.control.DropDown
        DropDown2Label          matlab.ui.control.Label
        EffectsandSampleModificationPanel  matlab.ui.container.Panel
        Knob2_2                 matlab.ui.control.Knob
        Knob2_2Label            matlab.ui.control.Label
        PlaybackDirectionLabel  matlab.ui.control.Label
        VolumeLevelLabel        matlab.ui.control.Label
        Slider2_6               matlab.ui.control.Slider
        Slider2_6Label          matlab.ui.control.Label
        Slider2_5               matlab.ui.control.Slider
        Slider2_5Label          matlab.ui.control.Label
        Slider2_4               matlab.ui.control.Slider
        Slider2_4Label          matlab.ui.control.Label
        Slider2_3               matlab.ui.control.Slider
        Slider2_3Label          matlab.ui.control.Label
        Slider2_2               matlab.ui.control.Slider
        Slider2_2Label          matlab.ui.control.Label
        Slider2                 matlab.ui.control.Slider
        Slider2Label            matlab.ui.control.Label
        Knob2                   matlab.ui.control.Knob
        Knob2Label              matlab.ui.control.Label
        Slider                  matlab.ui.control.Slider
        SliderLabel             matlab.ui.control.Label
        Switch                  matlab.ui.control.Switch
        VolumeKnob              matlab.ui.control.Knob
        VolumeKnobLabel         matlab.ui.control.Label
        SaveButton              matlab.ui.control.Button
        SampleNoDropDown        matlab.ui.control.DropDown
        SampleNoDropDownLabel   matlab.ui.control.Label
        SampleSelectionPanel    matlab.ui.container.Panel
        sampleSelectionMessage  matlab.ui.control.Label
        Load9Button             matlab.ui.control.Button
        Sample9Button           matlab.ui.control.Button
        Load8Button             matlab.ui.control.Button
        Sample8Button           matlab.ui.control.Button
        Load7Button             matlab.ui.control.Button
        Sample7Button           matlab.ui.control.Button
        Load6Button             matlab.ui.control.Button
        Sample6Button           matlab.ui.control.Button
        Load4Button             matlab.ui.control.Button
        Sample4Button           matlab.ui.control.Button
        Load5Button             matlab.ui.control.Button
        Sample5Button           matlab.ui.control.Button
        Load3Button             matlab.ui.control.Button
        Load2Button             matlab.ui.control.Button
        Load1Button             matlab.ui.control.Button
        Sample3Button           matlab.ui.control.Button
        Sample2Button           matlab.ui.control.Button
        Sample1Button           matlab.ui.control.Button
        sampleSoundWaveAxes     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        samplePathArr = strings([1, 9]); % Description
        playerArr = {};
        playFlagArr = zeros(1, 9);
    end
    
    methods (Access = private)
        
        function loadSample(app, idx)
            [file, path] = uigetfile('*.wav');
            if isequal(file,0)
                app.samepleSelectionMessage.Text = "User selected Cancel";
            else
                file = convertCharsToStrings(file);
                path = convertCharsToStrings(path);
                filepath = path + file;
                app.sampleSelectionMessage.Text = "User selected " + newline + filepath;
                app.samplePathArr(idx) = filepath;
            end
        end
        
        function playPauseAudio(app, idx)
            if((app.samplePathArr(idx)) == "")
                app.sampleSelectionMessage.Text = "Please load file first.";
                return;
            end
            
            
            filename = app.samplePathArr(idx);
            [audioData, sampleRate] = audioread(filename);
            t = (0:length(audioData) - 1) / sampleRate;
            plot(app.sampleSoundWaveAxes, t, audioData);
            app.playerArr{idx} = audioplayer(audioData, sampleRate);
            if app.playFlagArr(idx) == 0
                try
                    % Play audio using audioplayer
                    resume(app.playerArr{idx}); 
                    app.playFlagArr(idx) = 1;
                catch
                    app.sampleSelectionMessage.Text = "Error playing audio file.";
                end 
            else
                pause(app.playerArr{idx});
                app.playFlagArr(idx) = 0;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Sample1Button
        function Sample1ButtonPushed(app, event)
            app.playPauseAudio(1);
        end

        % Button pushed function: Load1Button
        function Load1ButtonPushed(app, event)
            app.loadSample(1);
        end

        % Button pushed function: Sample2Button
        function Sample2ButtonPushed(app, event)
            app.playPauseAudio(2);
        end

        % Button pushed function: Load2Button
        function Load2ButtonPushed(app, event)
            app.loadSample(2);
        end

        % Button pushed function: Sample3Button
        function Sample3ButtonPushed(app, event)
            app.playPauseAudio(3);
        end

        % Button pushed function: Load3Button
        function Load3ButtonPushed(app, event)
            app.loadSample(3);
        end

        % Button pushed function: Load4Button
        function Load4ButtonPushed(app, event)
            app.loadSample(4);
        end

        % Button pushed function: Sample4Button
        function Sample4ButtonPushed(app, event)
            app.playPauseAudio(4);
        end

        % Button pushed function: Load5Button
        function Load5ButtonPushed(app, event)
            app.loadSample(5);
        end

        % Button pushed function: Sample5Button
        function Sample5ButtonPushed(app, event)
            app.playPauseAudio(5);
        end

        % Button pushed function: Sample6Button
        function Sample6ButtonPushed(app, event)
            app.playPauseAudio(6);
        end

        % Button pushed function: Load6Button
        function Load6ButtonPushed(app, event)
            app.loadSample(6);
        end

        % Button pushed function: Sample7Button
        function Sample7ButtonPushed(app, event)
            app.playPauseAudio(7);
        end

        % Button pushed function: Load7Button
        function Load7ButtonPushed(app, event)
            app.loadSample(7);
        end

        % Button pushed function: Sample8Button
        function Sample8ButtonPushed(app, event)
            app.playPauseAudio(8);
        end

        % Button pushed function: Load8Button
        function Load8ButtonPushed(app, event)
            app.loadSample(8);
        end

        % Button pushed function: Sample9Button
        function Sample9ButtonPushed(app, event)
            app.playPauseAudio(9);
        end

        % Button pushed function: Load9Button
        function Load9ButtonPushed(app, event)
            app.loadSample(9);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.sampleSoundWaveAxes = uiaxes(app.UIFigure);
            title(app.sampleSoundWaveAxes, 'Soundwave')
            xlabel(app.sampleSoundWaveAxes, 'Time(s)')
            ylabel(app.sampleSoundWaveAxes, 'Amplitude')
            zlabel(app.sampleSoundWaveAxes, 'Z')
            app.SampleSelectionPanel = uipanel(app.UIFigure);
            app.SampleSelectionPanel.Title = 'Sample Selection';
            app.Sample1Button = uibutton(app.SampleSelectionPanel, 'push');
            app.Sample1Button.ButtonPushedFcn = createCallbackFcn(app, @Sample1ButtonPushed, true);
            app.Sample1Button.Text = 'Sample1';
            app.Sample2Button = uibutton(app.SampleSelectionPanel, 'push');
            app.Sample2Button.ButtonPushedFcn = createCallbackFcn(app, @Sample2ButtonPushed, true);
            app.Sample2Button.Text = 'Sample2';
            app.Sample3Button = uibutton(app.SampleSelectionPanel, 'push');
            app.Sample3Button.ButtonPushedFcn = createCallbackFcn(app, @Sample3ButtonPushed, true);
            app.Sample3Button.Text = 'Sample3';
            app.Load1Button = uibutton(app.SampleSelectionPanel, 'push');
            app.Load1Button.ButtonPushedFcn = createCallbackFcn(app, @Load1ButtonPushed, true);
            app.Load1Button.Text = 'Load1';
            app.Load2Button = uibutton(app.SampleSelectionPanel, 'push');
            app.Load2Button.ButtonPushedFcn = createCallbackFcn(app, @Load2ButtonPushed, true);
            app.Load2Button.Text = 'Load2';
            app.Load3Button = uibutton(app.SampleSelectionPanel, 'push');
            app.Load3Button.ButtonPushedFcn = createCallbackFcn(app, @Load3ButtonPushed, true);
            app.Load3Button.Text = 'Load3';
            app.Sample5Button = uibutton(app.SampleSelectionPanel, 'push');
            app.Sample5Button.ButtonPushedFcn = createCallbackFcn(app, @Sample5ButtonPushed, true);
            app.Sample5Button.Text = 'Sample5';
            app.Load5Button = uibutton(app.SampleSelectionPanel, 'push');
            app.Load5Button.ButtonPushedFcn = createCallbackFcn(app, @Load5ButtonPushed, true);
            app.Load5Button.Text = 'Load5';
            app.Sample4Button = uibutton(app.SampleSelectionPanel, 'push');
            app.Sample4Button.ButtonPushedFcn = createCallbackFcn(app, @Sample4ButtonPushed, true);
            app.Sample4Button.Text = 'Sample4';
            app.Load4Button = uibutton(app.SampleSelectionPanel, 'push');
            app.Load4Button.ButtonPushedFcn = createCallbackFcn(app, @Load4ButtonPushed, true);
            app.Load4Button.Text = 'Load4';
            app.Sample6Button = uibutton(app.SampleSelectionPanel, 'push');
            app.Sample6Button.ButtonPushedFcn = createCallbackFcn(app, @Sample6ButtonPushed, true);
            app.Sample6Button.Text = 'Sample6';
            app.Load6Button = uibutton(app.SampleSelectionPanel, 'push');
            app.Load6Button.ButtonPushedFcn = createCallbackFcn(app, @Load6ButtonPushed, true);
            app.Load6Button.Text = 'Load6';
            app.Sample7Button = uibutton(app.SampleSelectionPanel, 'push');
            app.Sample7Button.ButtonPushedFcn = createCallbackFcn(app, @Sample7ButtonPushed, true);
            app.Sample7Button.Text = 'Sample7';
            app.Load7Button = uibutton(app.SampleSelectionPanel, 'push');
            app.Load7Button.ButtonPushedFcn = createCallbackFcn(app, @Load7ButtonPushed, true);
            app.Load7Button.Text = 'Load7';
            app.Sample8Button = uibutton(app.SampleSelectionPanel, 'push');
            app.Sample8Button.ButtonPushedFcn = createCallbackFcn(app, @Sample8ButtonPushed, true);
            app.Sample8Button.Text = 'Sample8';
            app.Load8Button = uibutton(app.SampleSelectionPanel, 'push');
            app.Load8Button.ButtonPushedFcn = createCallbackFcn(app, @Load8ButtonPushed, true);
            app.Load8Button.Text = 'Load8';
            app.Sample9Button = uibutton(app.SampleSelectionPanel, 'push');
            app.Sample9Button.ButtonPushedFcn = createCallbackFcn(app, @Sample9ButtonPushed, true);
            app.Sample9Button.Text = 'Sample9';
            app.Load9Button = uibutton(app.SampleSelectionPanel, 'push');
            app.Load9Button.ButtonPushedFcn = createCallbackFcn(app, @Load9ButtonPushed, true);
            app.Load9Button.Text = 'Load9';
            app.sampleSelectionMessage = uilabel(app.SampleSelectionPanel);
            app.sampleSelectionMessage.Text = '';
            app.EffectsandSampleModificationPanel = uipanel(app.UIFigure);
            app.EffectsandSampleModificationPanel.Title = 'Effects and Sample Modification';
            app.SampleNoDropDownLabel = uilabel(app.EffectsandSampleModificationPanel);
            app.SampleNoDropDownLabel.Text = 'Sample No';
            app.SampleNoDropDown = uidropdown(app.EffectsandSampleModificationPanel);
            app.SaveButton = uibutton(app.EffectsandSampleModificationPanel, 'push');
            app.SaveButton.Text = 'Save';
            app.VolumeKnobLabel = uilabel(app.EffectsandSampleModificationPanel);
            app.VolumeKnobLabel.Text = 'Volume  ';
            app.VolumeKnob = uiknob(app.EffectsandSampleModificationPanel, 'continuous');
            app.Switch = uiswitch(app.EffectsandSampleModificationPanel, 'slider');
            app.Switch.Items = {'Backward', 'Forward'};
            app.Switch.Value = 'Backward';
            app.SliderLabel = uilabel(app.EffectsandSampleModificationPanel);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.EffectsandSampleModificationPanel);
            app.Knob2Label = uilabel(app.EffectsandSampleModificationPanel);
            app.Knob2Label.Text = 'Knob2';
            app.Knob2 = uiknob(app.EffectsandSampleModificationPanel, 'continuous');
            app.Slider2Label = uilabel(app.EffectsandSampleModificationPanel);
            app.Slider2Label.Text = 'Slider2';
            app.Slider2 = uislider(app.EffectsandSampleModificationPanel);
            app.Slider2_2Label = uilabel(app.EffectsandSampleModificationPanel);
            app.Slider2_2Label.Text = 'Slider2';
            app.Slider2_2 = uislider(app.EffectsandSampleModificationPanel);
            app.Slider2_3Label = uilabel(app.EffectsandSampleModificationPanel);
            app.Slider2_3Label.Text = 'Slider2';
            app.Slider2_3 = uislider(app.EffectsandSampleModificationPanel);
            app.Slider2_4Label = uilabel(app.EffectsandSampleModificationPanel);
            app.Slider2_4Label.Text = 'Slider2';
            app.Slider2_4 = uislider(app.EffectsandSampleModificationPanel);
            app.Slider2_5Label = uilabel(app.EffectsandSampleModificationPanel);
            app.Slider2_5Label.Text = 'Slider2';
            app.Slider2_5 = uislider(app.EffectsandSampleModificationPanel);
            app.Slider2_6Label = uilabel(app.EffectsandSampleModificationPanel);
            app.Slider2_6Label.Text = 'Slider2';
            app.Slider2_6 = uislider(app.EffectsandSampleModificationPanel);
            app.VolumeLevelLabel = uilabel(app.EffectsandSampleModificationPanel);
            app.VolumeLevelLabel.Text = 'Volume Level ';
            app.PlaybackDirectionLabel = uilabel(app.EffectsandSampleModificationPanel);
            app.PlaybackDirectionLabel.Text = 'Playback Direction';
            app.Knob2_2Label = uilabel(app.EffectsandSampleModificationPanel);
            app.Knob2_2Label.Text = 'Knob2';
            app.Knob2_2 = uiknob(app.EffectsandSampleModificationPanel, 'continuous');
            app.Panel3 = uipanel(app.UIFigure);
            app.Panel3.Title = 'Panel3';
            app.DropDown2Label = uilabel(app.Panel3);
            app.DropDown2Label.Text = 'Drop Down2';
            app.DropDown2 = uidropdown(app.Panel3);
            app.Button3_2 = uibutton(app.Panel3, 'push');
            app.Button3_2.Text = 'Button3';
            app.Slider3Label = uilabel(app.Panel3);
            app.Slider3Label.Text = 'Slider3';
            app.Slider3 = uislider(app.Panel3);
            app.Slider3_2Label = uilabel(app.Panel3);
            app.Slider3_2Label.Text = 'Slider3';
            app.Slider3_2 = uislider(app.Panel3);
            app.Panel4 = uipanel(app.UIFigure);
            app.Panel4.Title = 'Panel4';
            app.UIAxes2 = uiaxes(app.Panel4);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.DropDown3Label = uilabel(app.Panel4);
            app.DropDown3Label.Text = 'Drop Down3';
            app.DropDown3 = uidropdown(app.Panel4);
            app.Button4 = uibutton(app.Panel4, 'push');
            app.Button4.Text = 'Button4';
            app.Panel5 = uipanel(app.Panel4);
            app.Panel5.Title = 'Panel5';
            app.CheckBox = uicheckbox(app.Panel5);
            app.CheckBox2 = uicheckbox(app.Panel5);
            app.CheckBox2.Text = 'Check Box2';
            app.CheckBox3 = uicheckbox(app.Panel5);
            app.CheckBox3.Text = 'Check Box3';
            app.CheckBox4 = uicheckbox(app.Panel5);
            app.CheckBox4.Text = 'Check Box4';
            app.Button_14 = uibutton(app.Panel4, 'push');
            app.Button_14.Text = '';
            app.Button_15 = uibutton(app.Panel4, 'push');
            app.Button_15.Text = '';
            app.Button_16 = uibutton(app.Panel4, 'push');
            app.Button_16.Text = '';
            app.Button_11 = uibutton(app.Panel4, 'push');
            app.Button_11.Text = '';
            app.Button_12 = uibutton(app.Panel4, 'push');
            app.Button_12.Text = '';
            app.Button_13 = uibutton(app.Panel4, 'push');
            app.Button_13.Text = '';
            app.Button_17 = uibutton(app.Panel4, 'push');
            app.Button_17.Text = '';
            app.Button5_9 = uibutton(app.Panel4, 'push');
            app.Button5_9.Text = 'Button5';
            app.Button5_10 = uibutton(app.Panel4, 'push');
            app.Button5_10.Text = 'Button5';
            app.Button5_11 = uibutton(app.Panel4, 'push');
            app.Button5_11.Text = 'Button5';
            app.Button5_12 = uibutton(app.Panel4, 'push');
            app.Button5_12.Text = 'Button5';
            app.Button5_13 = uibutton(app.Panel4, 'push');
            app.Button5_13.Text = 'Button5';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end